classdef ThorlabsScientificCameraInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThorlabsScientificCameraInterfaceUIFigure  matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        ImageAcquisitionPanel          matlab.ui.container.Panel
        NewBackgroundImageButton       matlab.ui.control.Button
        NewNormalizationImageButton    matlab.ui.control.Button
        BeginImageAcquisitionButton    matlab.ui.control.Button
        FramestoAverageforBackgroundEditFieldLabel  matlab.ui.control.Label
        FramestoAverageforBackgroundEditField  matlab.ui.control.NumericEditField
        FramestoAverageforNormalizationEditFieldLabel  matlab.ui.control.Label
        FramestoAverageforNormalizationEditField  matlab.ui.control.NumericEditField
        ExposureTimeusEditFieldLabel   matlab.ui.control.Label
        ExposureTimeusEditField        matlab.ui.control.NumericEditField
        FramestoAcquire0forunlimitedEditFieldLabel  matlab.ui.control.Label
        FramestoAcquire0forunlimitedEditField  matlab.ui.control.NumericEditField
        StopImageAcquisitionButton     matlab.ui.control.Button
        TriggerModeButtonGroup         matlab.ui.container.ButtonGroup
        ExternalButton                 matlab.ui.control.RadioButton
        InternalButton                 matlab.ui.control.RadioButton
        FramestoAverageEditFieldLabel  matlab.ui.control.Label
        FramestoAverageEditField       matlab.ui.control.NumericEditField
        FigureSettingsPanel            matlab.ui.container.Panel
        RegionofInterestandBinningPanel  matlab.ui.container.Panel
        OriginXEditFieldLabel          matlab.ui.control.Label
        OriginXEditField               matlab.ui.control.NumericEditField
        OriginYEditFieldLabel          matlab.ui.control.Label
        OriginYEditField               matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        ResetROIandBinButton           matlab.ui.control.Button
        BinXLabel                      matlab.ui.control.Label
        BinXEditField                  matlab.ui.control.NumericEditField
        BinYEditFieldLabel             matlab.ui.control.Label
        BinYEditField                  matlab.ui.control.NumericEditField
        SetROIandBinButton             matlab.ui.control.Button
        BackgroundCorrectionandNormalizationPanel  matlab.ui.container.Panel
        Switch                         matlab.ui.control.Switch
        ScaleFactorEditFieldLabel      matlab.ui.control.Label
        ScaleFactorEditField           matlab.ui.control.NumericEditField
        TestButton                     matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        ErrorToleranceLabel            matlab.ui.control.Label
        ErrorToleranceEditField        matlab.ui.control.NumericEditField
        ClearFigureWindowButton        matlab.ui.control.Button
        SaveImagesButton               matlab.ui.control.Button
        FrameRate0fpsLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        tlCamera                        % Camera instance
        tlCameraSDK                     % Camera SDK
        serialNumbers                   % Camera serial numbers
        ROIandBin                       % Placeholder ROI and bin settings
        B                               % Background image
        N                               % Normalization image
        CroppedB                        % Background image cropped to match ROI
        CroppedN                        % Normalization image cropped to match ROI
        Images                          % Array of captured images
        CurrentImageIndex               % Frame number of frame in image window
        StopAcquisition                 % Boolean used to stop acquisition
        ImageSettings                   % List of settings at the time of acquisition
        ROISelect                       % Boolean that tells whether the user has selected an ROI for saving after acquisition
    end
    
    methods (Access = private)
        
        % Apply background correction and normalization to the image or image array R
        function S = BackgroundCorrectionAndNormalization(app, R)
            % Convert to doubles to avoid information loss
            R = double(R);
            B = double(app.CroppedB);
            N = double(app.CroppedN);
            s = double(app.ScaleFactorEditField.Value);
            S = s*(R-B)./(1+(N-B));
        end
    
        % Undo background correction and normalization to recover raw image data
        function R = UndoBackgroundCorrectionAndNormalization(app, S)
            % Convert to doubles to avoid information loss
            B = double(app.CroppedB);
            N = double(app.CroppedN);
            s = double(app.ScaleFactorEditField.Value);
            R = ((S .* (1+(N-B))) ./ s) + B;
        end
        
        % Acquire and return the average of NumberOfFramesToAverage frames
        function AverageFrame = AverageFrame(app, NumberOfFramesToAverage)
            FramesToAverage = zeros(app.tlCamera.ROIAndBin.ROIWidth_pixels / app.tlCamera.ROIAndBin.BinX, app.tlCamera.ROIAndBin.ROIHeight_pixels / app.tlCamera.ROIAndBin.BinY, NumberOfFramesToAverage);
            for FrameCount=1:NumberOfFramesToAverage
                if (app.tlCamera.NumberOfQueuedFrames > 0)
                    imageFrame = app.tlCamera.GetPendingFrameOrNull;
                    if ~isempty(imageFrame)
                        imageData = uint16(imageFrame.ImageData.ImageData_monoOrBGR);
                        imageHeight = imageFrame.ImageData.Height_pixels;
                        imageWidth = imageFrame.ImageData.Width_pixels;
                        FramesToAverage(:,:,FrameCount) = reshape(imageData, [imageWidth, imageHeight]);
                    end
                    % Release the image frame
                    delete(imageFrame);
                end
            end
            AverageFrame = mean(FramesToAverage, 3);
        end
        
        % Display frame on UIAxes
        function ShowFrame(app, frame, titleString)
            imagesc(frame','Parent',app.UIAxes);
            app.UIAxes.Title.String = titleString;
            drawnow();
            colorbar(app.UIAxes);
            colormap(app.UIAxes, gray);
        end
        
        % Update axes according to current ROI and bin settings
        function UpdateAxes(app)
            app.UIAxes.XTick = 0:100/app.tlCamera.ROIAndBin.BinX:app.tlCamera.ROIAndBin.ROIWidth_pixels;
            % we need to pull some tricks here to maintain the correct axis labels with binning
            app.UIAxes.XTickLabel = roundn(double(app.tlCamera.ROIAndBin.BinX*(app.tlCamera.ROIAndBin.ROIOriginX_pixels/app.tlCamera.ROIAndBin.BinX: ...
                100/app.tlCamera.ROIAndBin.BinX: ...
                (app.tlCamera.ROIAndBin.ROIOriginX_pixels+app.tlCamera.ROIAndBin.ROIWidth_pixels)/app.tlCamera.ROIAndBin.BinX)), 2);
            app.UIAxes.XLim = [1,double(app.tlCamera.ROIAndBin.ROIWidth_pixels / app.tlCamera.ROIAndBin.BinX)+.5];
            app.UIAxes.YTick = 0:100/app.tlCamera.ROIAndBin.BinY:app.tlCamera.ROIAndBin.ROIHeight_pixels;
            app.UIAxes.YTickLabel = roundn(double(app.tlCamera.ROIAndBin.BinY*(app.tlCamera.ROIAndBin.ROIOriginY_pixels/app.tlCamera.ROIAndBin.BinY: ...
                100/app.tlCamera.ROIAndBin.BinY: ...
                (app.tlCamera.ROIAndBin.ROIOriginY_pixels+app.tlCamera.ROIAndBin.ROIHeight_pixels)/app.tlCamera.ROIAndBin.BinY)), 2);
            app.UIAxes.YLim = [1,double(app.tlCamera.ROIAndBin.ROIHeight_pixels / app.tlCamera.ROIAndBin.BinY)+.5];
        end
        
        % Reset to full window: BinX, BinY, OriginX, OriginY -> 0, Width, Height -> max values
        function ResetROIandBin(app)
           app.tlCamera.ROIAndBin.ROIOriginX_pixels = 0;
           app.tlCamera.ROIAndBin.ROIOriginY_pixels = 0;
           app.tlCamera.ROIAndBin.ROIWidth_pixels = app.tlCamera.SensorWidth_pixels;
           app.tlCamera.ROIAndBin.ROIHeight_pixels = app.tlCamera.SensorHeight_pixels;
           app.tlCamera.ROIAndBin.BinX = 1;
           app.tlCamera.ROIAndBin.BinY = 1;
           app.ROIandBin = app.tlCamera.ROIAndBin;
           % Set GUI field values
           app.OriginXEditField.Value = double(app.tlCamera.ROIAndBin.ROIOriginX_pixels);
           app.OriginYEditField.Value = double(app.tlCamera.ROIAndBin.ROIOriginY_pixels);
           app.WidthEditField.Value = double(app.tlCamera.SensorWidth_pixels);
           app.HeightEditField.Value = double(app.tlCamera.SensorHeight_pixels);
           app.BinXEditField.Value = double(app.tlCamera.ROIAndBin.BinX);
           app.BinYEditField.Value = double(app.tlCamera.ROIAndBin.BinY);
           UpdateAndClearImageWindow(app)
        end
        
        % Clear UIAxes and update ROI and bin settings
        function UpdateAndClearImageWindow(app)
            cla(app.UIAxes)
            app.UIAxes.Title.String = "";
            UpdateAxes(app)
        end
        
        % Disable fields during image acquisition
        function DisableFields(app)
           app.OriginXEditField.Enable = 'off';
           app.OriginYEditField.Enable = 'off';
           app.WidthEditField.Enable = 'off';
           app.HeightEditField.Enable = 'off';
           app.ResetROIandBinButton.Enable = 'off';
           app.PreviousButton.Enable = 'off';
           app.NextButton.Enable = 'off';
           app.SaveImagesButton.Enable = 'off';
           app.NewBackgroundImageButton.Enable = 'off';
           app.NewNormalizationImageButton.Enable = 'off';
           app.BeginImageAcquisitionButton.Enable = 'off';          
           app.FramestoAverageforBackgroundEditField.Enable = 'off';
           app.FramestoAverageforNormalizationEditField.Enable = 'off';
           app.ExposureTimeusEditField.Enable = 'off';
           app.FramestoAcquire0forunlimitedEditField.Enable = 'off';
           app.ScaleFactorEditField.Enable = 'off';
           app.ExternalButton.Enable = 'off';
           app.InternalButton.Enable = 'off';
           app.BinXEditField.Enable = 'off';
           app.BinYEditField.Enable = 'off';
           app.SetROIandBinButton.Enable = 'off';
           app.Switch.Enable = 'off';
           app.FramestoAverageEditField.Enable = 'off';
           app.ClearFigureWindowButton.Enable = 'off';
           app.TestButton.Enable = 'off';
           app.ErrorToleranceEditField.Enable = 'off';
        end
        
        % Enable fields after image acquisition
        function EnableFields(app)
           app.PreviousButton.Enable = 'on';
           app.NextButton.Enable = 'on';
           app.SaveImagesButton.Enable = 'on';
           app.FramestoAverageforBackgroundEditField.Enable = 'on';
           app.FramestoAverageforNormalizationEditField.Enable = 'on';
           app.ExposureTimeusEditField.Enable = 'on';
           app.FramestoAcquire0forunlimitedEditField.Enable = 'on';
           app.ScaleFactorEditField.Enable = 'on';
           app.ExternalButton.Enable = 'on';
           app.InternalButton.Enable = 'on';
           app.Switch.Enable = 'on';
           app.FramestoAverageEditField.Enable = 'on';
           app.ClearFigureWindowButton.Enable = 'on';
           app.TestButton.Enable = 'on';
           app.OriginXEditField.Enable = 'on';
           app.OriginYEditField.Enable = 'on';
           app.WidthEditField.Enable = 'on';
           app.HeightEditField.Enable = 'on';
           app.BinXEditField.Enable = 'on';
           app.BinYEditField.Enable = 'on';
           app.SetROIandBinButton.Enable = 'on';
           app.ErrorToleranceEditField.Enable = 'on';
           app.StopImageAcquisitionButton.Enable = 'off';
        end
        
        function UpdateOriginX(app)
            originX = app.OriginXEditField.Value;
            % If the entry is valid, set camera TextboxBinY
            if (~isnan(originX))
                % Check if the entry is within range.
                if (originX > app.tlCamera.SensorWidth_pixels - 1)
                    originX = app.tlCamera.SensorWidth_pixels - 1;
                else
                    if (originX < 0)
                        originX = 0;
                    end
                end
                app.OriginXEditField.Value = originX;
                % Camera ROI and Bin needs to be set together. 
                app.ROIandBin.ROIOriginX_pixels = originX;
                app.tlCamera.ROIAndBin = app.ROIandBin;
            else
                % Restore the field to camera value
                app.ROIandBin = app.tlCamera.ROIAndBin;
                app.OriginXEditField.Value = app.ROIandBin.ROIOriginX_pixels;
            end
        end
        
        function UpdateOriginY(app)
            originY = app.OriginYEditField.Value;
            % If the entry is valid, set camera TextboxBinY
            if (~isnan(originY))
                % Check if the entry is within range.
                if (originY > app.tlCamera.SensorHeight_pixels - 1)
                    originY = app.tlCamera.SensorHeight_pixels - 1;
                else
                    if (originY < 0)
                        originY = 0;
                    end
                end
                app.OriginYEditField.Value = originY;
                % Camera ROI and Bin needs to be set together. 
                app.ROIandBin.ROIOriginY_pixels = originY;
                app.tlCamera.ROIAndBin = app.ROIandBin;
            else
                % Restore the field to camera value
                app.ROIandBin = app.tlCamera.ROIAndBin;
                app.OriginYEditField.Value = app.ROIandBin.ROIOriginX_pixels;
            end
        end
        
        function UpdateHeight(app)
            height = app.HeightEditField.Value;
            % If the entry is valid, set camera TextboxBinY
            if (~isnan(height))
                % Check if the entry is within range.
                maxHeight = double(app.tlCamera.SensorHeight_pixels - app.ROIandBin.ROIOriginY_pixels);
                if (height > maxHeight)
                    height = maxHeight;
                else
                    if (height < 1)
                        height = 1;
                    end
                end
                app.HeightEditField.Value = height;
                % Camera ROI and Bin needs to be set together. 
                app.ROIandBin.ROIHeight_pixels = height;
                app.tlCamera.ROIAndBin = app.ROIandBin;
            else
                % Restore the field to camera value
                app.ROIandBin = handles.tlCamera.ROIAndBin;
                app.HeightEditField.Value = app.ROIandBin.ROIHeight_pixels;
            end
        end
        
        function UpdateWidth(app)
            width = app.WidthEditField.Value;
            % If the entry is valid, set camera TextboxBinY
            if (~isnan(width))
                % Check if the entry is within range.
                maxWidth = double(app.tlCamera.SensorWidth_pixels - app.ROIandBin.ROIOriginX_pixels);
                if (width > maxWidth)
                    width = maxWidth;
                else
                    if (width < 1)
                        width = 1;
                    end
                end
                app.WidthEditField.Value = width;
                % Camera ROI and Bin needs to be set together. 
                app.ROIandBin.ROIWidth_pixels = width;
                app.tlCamera.ROIAndBin = app.ROIandBin;
            else
                % Restore the field to camera value
                app.ROIandBin = handles.tlCamera.ROIAndBin;
                app.WidthEditField.Value = app.ROIandBin.ROIWidth_pixels;
            end    
        end
        
        function UpdateBinX(app)
            binx = app.BinXEditField.Value;
            if (~isnan(binx))
                if binx < 1
                    binx = 1;
                elseif binx > app.tlCamera.ROIAndBin.ROIWidth_pixels
                    binx = app.tlCamera.ROIAndBin.ROIWidth_pixels;
                end
                app.BinXEditField.Value = binx;
                app.ROIandBin.BinX = binx;
                app.tlCamera.ROIAndBin = app.ROIandBin;
            else
                app.ROIandBin.BinX = app.tlCamera.ROIAndBin.BinX;
                app.BinXEditField.Value = app.tlCamera.ROIAndBin.BinX;
            end
        end
        
        function UpdateBinY(app)
            biny = app.BinYEditField.Value;
            if (~isnan(biny))
                if biny < 1
                    biny = 1;
                end
                if biny > app.tlCamera.ROIAndBin.ROIOriginY_pixels
                    biny = app.tlCamera.ROIAndBin.ROIOriginY_pixels;
                end
                app.BinYEditField.Value = biny;
                app.ROIandBin.BinY = biny;
                app.tlCamera.ROIAndBin = app.ROIandBin;
            else
                app.ROIandBin.BinY = app.tlCamera.ROIAndBin.BinY;
                app.BinYEditField.Value = app.tlCamera.ROIAndBin.BinY;
            end
        end
        
        % Storing image settings in a Map for saving
        function UpdateImageSettings(app)       
            ROIAndBinDict = containers.Map;
            ROIAndBinDict('Origin X') = uint16(app.tlCamera.ROIAndBin.ROIOriginX_pixels);
            ROIAndBinDict('Origin Y') = uint16(app.tlCamera.ROIAndBin.ROIOriginY_pixels);
            ROIAndBinDict('Width') = uint16(app.tlCamera.ROIAndBin.ROIWidth_pixels);
            ROIAndBinDict('Height') = uint16(app.tlCamera.ROIAndBin.ROIHeight_pixels);
            ROIAndBinDict('Bin X') = uint16(app.tlCamera.ROIAndBin.BinX);
            ROIAndBinDict('Bin Y') = uint16(app.tlCamera.ROIAndBin.BinY);
            app.ImageSettings = containers.Map;
            app.ImageSettings('ROI and Bin') = ROIAndBinDict;
            app.ImageSettings('Exposure Time') = uint16(app.tlCamera.ExposureTime_us);
            app.ImageSettings('Scale Factor') = uint16(app.ScaleFactorEditField.Value);
            app.ImageSettings('Background Image') = app.CroppedB;
            app.ImageSettings('Normalization Image') = app.CroppedN;            
        end
        
        % Draws a rectangle representing a proposed region of interest and holds for 2 seconds
        function coords = DrawRectangle(app)
            f = figure('Position', [400, 150, 640, 480],'Name','Drag the mouse to create a rectangle about the region of interest');
            imshow(app.Images(:,:,app.CurrentImageIndex)',[]) % ignore warning
            r = getrect;
            rectangle('Position',[r(1) r(2) r(3) r(4)], 'EdgeColor','r','LineWidth',3)
            coords = {r(1) r(2) r(3) r(4)};
            pause(2)
            close(f)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uipanel(app.ThorlabsScientificCameraInterfaceUIFigure)
            colorbar(app.UIAxes);
            colormap(app.UIAxes, gray);
            app.UIAxes.Color = 'w';
            app.UIAxes.Title.String = "";
            app.ThorlabsScientificCameraInterfaceUIFigure.Children = flip(app.ThorlabsScientificCameraInterfaceUIFigure.Children);
            
            % Load camera and SDK
            if (isempty(app.tlCameraSDK))
                NET.addAssembly([pwd, '\Thorlabs.TSI.TLCamera.dll']);
                app.tlCameraSDK = Thorlabs.TSI.TLCamera.TLCameraSDK.OpenTLCameraSDK;
                % Get serial numbers of connected TLCameras.
                app.serialNumbers = app.tlCameraSDK.DiscoverAvailableCameras;
                if (app.serialNumbers.Count > 0)
                    % Open the first TLCamera using the serial number and set the exposure time.
                    app.tlCamera = app.tlCameraSDK.OpenCamera(app.serialNumbers.Item(0), false);
                    app.tlCamera.ExposureTime_us = app.ExposureTimeusEditField.Value;
                end
            end
            
            % Load images for background correction and normalization
            Background = load('BCN.mat', 'Background');
            Background = cell2mat(struct2cell(Background));
            app.B = uint16(Background);
            app.CroppedB = app.B;
            Normalization = load('BCN.mat', 'Normalization');
            Normalization = cell2mat(struct2cell(Normalization));
            app.N = uint16(Normalization);
            app.CroppedN = app.N;

            % Setting Taps = 1 and DataRate = 40MHz (max)
            app.tlCamera.Taps = Thorlabs.TSI.TLCameraInterfaces.Taps.SingleTap;
            app.tlCamera.DataRate = Thorlabs.TSI.TLCameraInterfaces.DataRate.ReadoutSpeed40MHz;
            
            ResetROIandBin(app)
            app.StopAcquisition = false;
            app.ROISelect = false;
        end

        % Button pushed function: NewBackgroundImageButton
        function NewBackgroundImageButtonPushed(app, event)
            app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.Arm;
            app.tlCamera.IssueSoftwareTrigger;
            app.B = AverageFrame(app, app.FramestoAverageforBackgroundEditField.Value);
            app.tlCamera.Disarm;
            % Update axes according to ROI and show image 
            UpdateAndClearImageWindow(app)
            imagesc(app.B','Parent',app.UIAxes);
            colorbar(app.UIAxes);
            colormap(app.UIAxes, gray);
            app.UIAxes.Title.String = "Background Image";
            % Save background and normalization images together in the current directory
            Background = app.B;
            Normalization = app.N;
            save('BCN.mat', 'Background', 'Normalization');
        end

        % Close request function: 
        % ThorlabsScientificCameraInterfaceUIFigure
        function ThorlabsScientificCameraInterfaceUIFigureCloseRequest(app, event)
            % Delete camera and SDK
            if ~isempty(app.tlCamera)
                % if the camera is armed, disarm.
                if (app.tlCamera.IsArmed)
                    app.tlCamera.Disarm;
                end
                app.tlCamera.Dispose;
                delete(app.tlCamera);
                app.tlCameraSDK.Dispose;
                delete(app.tlCameraSDK);
            end
            % Close window
            delete(app)
        end

        % Button pushed function: NewNormalizationImageButton
        function NewNormalizationImageButtonPushed(app, event)
            app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.Arm;
            app.tlCamera.IssueSoftwareTrigger;
            app.N = AverageFrame(app, app.FramestoAverageforNormalizationEditField.Value);
            app.tlCamera.Disarm;
            % Update axes according to ROI and show image
            UpdateAndClearImageWindow(app)
            imagesc(app.N','Parent',app.UIAxes);
            colorbar(app.UIAxes);
            colormap(app.UIAxes, gray);
            app.UIAxes.Title.String = "Normalization Image";  
            % Save background and normalization images together in current directory
            Normalization = app.N;
            Background = app.B;
            save('BCN.mat', 'Background', 'Normalization');         
        end

        % Button pushed function: BeginImageAcquisitionButton
        function BeginImageAcquisitionButtonPushed(app, event)
            % Throw an error if background or normalization image is too small for current ROI settings 
            if size(app.B, 1) < app.tlCamera.ROIAndBin.ROIWidth_pixels || size(app.B, 2) < app.tlCamera.ROIAndBin.ROIHeight_pixels
                f = uifigure;
                f.Position = [550, 350, 400, 200];
                uialert(f, 'Background and normalization images cannot be fit to ROI. Please capture new background and normalization images or change the ROI settings.', 'Error')
            else
                % Display a progress bar until CPU is ready for acquisition
                f = uifigure;
                f.Position = [550, 350, 400, 200];
                f.Name = "Loading";
                d = uiprogressdlg(f,'Title','Preparing for image acquisition.', 'Indeterminate','on');
                app.StopAcquisition = false;
                % Disable all fields besides 'Stop Acquisition'
                DisableFields(app);
                % Give the disable fields function time to run
                pause(1)
                
                UpdateAndClearImageWindow(app);
                
                % Cropping background and normalization images to fit current ROI settings                 
                app.CroppedB = imcrop(app.B, [app.tlCamera.ROIAndBin.ROIOriginX_pixels ...
                    app.tlCamera.ROIAndBin.ROIOriginY_pixels app.tlCamera.ROIAndBin.ROIHeight_pixels ...
                    app.tlCamera.ROIAndBin.ROIWidth_pixels]);
                app.CroppedN = imcrop(app.N, [app.tlCamera.ROIAndBin.ROIOriginX_pixels ...
                    app.tlCamera.ROIAndBin.ROIOriginY_pixels app.tlCamera.ROIAndBin.ROIHeight_pixels ...
                    app.tlCamera.ROIAndBin.ROIWidth_pixels]);
                
                % store current settings for saving with images
                UpdateImageSettings(app)
                
                % Set up and arm camera
                switch app.TriggerModeButtonGroup.SelectedObject.Text
                    case 'Internal'
                        app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
                        app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
                        app.tlCamera.Arm;
                        app.tlCamera.IssueSoftwareTrigger;
                    case 'External'
                        app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.HardwareTriggered;
                        app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
                        app.tlCamera.Arm;                
                end
                colorbar(app.UIAxes);
                colormap(app.UIAxes, gray);

                % Clear image array
                app.Images = zeros(floor(double(app.tlCamera.ROIAndBin.ROIWidth_pixels) / double(app.tlCamera.ROIAndBin.BinX)), floor(double(app.tlCamera.ROIAndBin.ROIHeight_pixels) / double(app.tlCamera.ROIAndBin.BinY)), 200, 'uint16');              
                FramesToAverage = zeros(floor(double(app.tlCamera.ROIAndBin.ROIWidth_pixels) / double(app.tlCamera.ROIAndBin.BinX)), ...
                    floor(double(app.tlCamera.ROIAndBin.ROIHeight_pixels) / double(app.tlCamera.ROIAndBin.BinY)), ...
                    app.FramestoAverageEditField.Value, 'uint16');
                app.CurrentImageIndex = 0;
                app.ROISelect = false;
                  
                % Close progress bar
                close(d);
                close(f);
                
                % Begin image acquisition
                keepRunning = true;
                while keepRunning
                    FrameCount = 1; % used to tell the program to average the last FramestoAverageEditField acquired frames
                    app.CurrentImageIndex = app.CurrentImageIndex + 1; % used to track which frame we are displaying
                    tic % Use tic/toc to time frame rate
                    while FrameCount <= app.FramestoAverageEditField.Value
                        % Wait for external trigger
                        if (app.tlCamera.NumberOfQueuedFrames > 0)
                            imageFrame = app.tlCamera.GetPendingFrameOrNull;
                            if ~isempty(imageFrame)
                                imageData = uint16(imageFrame.ImageData.ImageData_monoOrBGR);
                                imageHeight = imageFrame.ImageData.Height_pixels;
                                imageWidth = imageFrame.ImageData.Width_pixels;
                                FramesToAverage(:,:,FrameCount) = reshape(imageData, [imageWidth, imageHeight]);
                            end
                            % Release the image frame
                            delete(imageFrame);
                            FrameCount = FrameCount + 1;
                        end
                    end
                    % Average acquired images
                    app.Images(:,:,app.CurrentImageIndex) = mean(FramesToAverage,3);
                        
                    % Apply background correction and normalization to frame if the switch is on
                    if app.Switch.Value == "On"
                        app.Images(:,:,app.CurrentImageIndex) = BackgroundCorrectionAndNormalization(app, app.Images(:,:,app.CurrentImageIndex));
                    end

                    % Display the frame to UIAxes
                    titleString = sprintf('Average of Frames %d-%d', app.CurrentImageIndex*app.FramestoAverageEditField.Value-app.FramestoAverageEditField.Value+1, app.CurrentImageIndex*app.FramestoAverageEditField.Value);
                    ShowFrame(app, app.Images(:,:,app.CurrentImageIndex), titleString);
                    
                    % Calculate and display frame rate
                    FrameRate = uint16(app.FramestoAverageEditField.Value / toc);
                    app.FrameRate0fpsLabel.Text = sprintf('Frame Rate: %d fps', FrameRate);
                    app.FrameRate0fpsLabel.Visible = true;                    
                    
                    % Stop if all images have been acquired or if StopAcquisition Button is pressed
                    if (app.FramestoAcquire0forunlimitedEditField.Value > 0 && app.CurrentImageIndex >= app.FramestoAcquire0forunlimitedEditField.Value / app.FramestoAverageEditField.Value) || app.StopAcquisition || app.CurrentImageIndex == size(app.Images, 3)
                        keepRunning = false;
                        app.FrameRate0fpsLabel.Visible = false;
                    end
                end 
            end
            % Enable all fields
            EnableFields(app);
            % Disarm camera
            app.tlCamera.Disarm;
            %Crop off unused part of image array
            app.Images = app.Images(:,:,1:app.CurrentImageIndex);
        end

        % Button pushed function: ResetROIandBinButton
        function ResetROIandBinButtonPushed(app, event)
            ResetROIandBin(app)
        end

        % Value changed function: ExposureTimeusEditField
        function ExposureTimeusEditFieldValueChanged(app, event)
            app.tlCamera.ExposureTime_us = app.ExposureTimeusEditField.Value;
        end

        % Button pushed function: StopImageAcquisitionButton
        function StopImageAcquisitionButtonPushed(app, event)
            app.StopAcquisition = true;
        end

        % Button pushed function: SetROIandBinButton
        function SetROIandBinButtonPushed(app, event)
            % Open progress bar
            f = uifigure;
            f.Position = [550, 350, 400, 200];
            f.Name = "Loading";
            d = uiprogressdlg(f,'Title','Updating ROI and bin settings.', 'Indeterminate','on');
            % Update relevant settings
            UpdateOriginX(app)
            UpdateOriginY(app)
            UpdateWidth(app)
            UpdateHeight(app)
            UpdateBinX(app)
            UpdateBinY(app)
            if isempty(app.Images)
                UpdateAndClearImageWindow(app)
            else
                % if frames have already been acquired, plot a rectangle with the new ROI and update settings for saving
                rect = findall(app.UIAxes,'Type', 'Rectangle'); 
                delete(rect); 
                rectangle(app.UIAxes, 'Position', [app.ROIandBin.ROIOriginX_pixels,app.ROIandBin.ROIOriginY_pixels,app.ROIandBin.ROIWidth_pixels,app.ROIandBin.ROIHeight_pixels], 'EdgeColor','r','LineWidth',3,'LineStyle',"-")
                UpdateImageSettings(app)
                app.ROISelect = true;
            end
            % Close progress bar
            close(d);
            close(f);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            % Apply or undo background correction and normalization
            if ~isempty(app.Images)
                % Open progress bar
                f = uifigure;
                f.Position = [550, 350, 400, 200];
                f.Name = "Loading";
                switch app.Switch.Value
                    case 'On'
                        d = uiprogressdlg(f,'Title','Applying background correction and normalization.', 'Indeterminate','on');
                        app.Images = BackgroundCorrectionAndNormalization(app, app.Images);
                    case 'Off'
                        d = uiprogressdlg(f,'Title','Undoing background correction and normalization.', 'Indeterminate','on');
                        app.Images = UndoBackgroundCorrectionAndNormalization(app, app.Images);
                end
                % Close progress bar
                close(d);
                close(f);
                % Plot updated image
                titleString = sprintf('Average of Frames %d-%d', app.CurrentImageIndex*app.FramestoAverageEditField.Value-app.FramestoAverageEditField.Value+1, app.CurrentImageIndex*app.FramestoAverageEditField.Value);
                ShowFrame(app, app.Images(:,:,app.CurrentImageIndex),titleString);
            end 
        end

        % Button pushed function: ClearFigureWindowButton
        function ClearFigureWindowButtonPushed(app, event)
            %Clear UIAxes, clear image array, enable fields
            cla(app.UIAxes)
            app.UIAxes.Title.String = "";
            app.Images = [];
            app.ResetROIandBinButton.Enable = 'on';
            app.NewBackgroundImageButton.Enable = 'on';
            app.NewNormalizationImageButton.Enable = 'on';
            app.BeginImageAcquisitionButton.Enable = 'on'; 
            app.StopImageAcquisitionButton.Enable = 'on';
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            % For browsing acquired images
            if ~isempty(app.Images) && app.CurrentImageIndex > 1
                app.CurrentImageIndex = app.CurrentImageIndex-1;
                titleString = sprintf('Average of Frames %d-%d', app.CurrentImageIndex*app.FramestoAverageEditField.Value-app.FramestoAverageEditField.Value+1, app.CurrentImageIndex*app.FramestoAverageEditField.Value);
                ShowFrame(app, app.Images(:,:,app.CurrentImageIndex),titleString);
                drawnow();
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % For browsing acquired images
            if ~isempty(app.Images) && app.CurrentImageIndex < size(app.Images,3)
                app.CurrentImageIndex = app.CurrentImageIndex+1;
                titleString = sprintf('Average of Frames %d-%d', app.CurrentImageIndex*app.FramestoAverageEditField.Value-app.FramestoAverageEditField.Value+1, app.CurrentImageIndex*app.FramestoAverageEditField.Value);
                ShowFrame(app, app.Images(:,:,app.CurrentImageIndex),titleString);
                drawnow();
            end
        end

        % Button pushed function: SaveImagesButton
        function SaveImagesButtonPushed(app, event)
            % Save images acquired as an array and image settings as a Map
            if ~isempty(app.Images)
                % If the user has not specified a new ROI since acquiring
                % images, give them the option to do so
                if ~app.ROISelect
                    answer = questdlg('Would you like to specify a new region of interest for saving?','ROI Select','Yes','No','No');
                    switch answer
                        case 'Yes'
                            % Allow the user to draw a rectangle specifying the ROI until they are satsified
                            done = false;
                            while ~done
                                coords = DrawRectangle(app);
                                [app.tlCamera.ROIAndBin.ROIOriginX_pixels, app.tlCamera.ROIAndBin.ROIOriginY_pixels, ...
                                    app.tlCamera.ROIAndBin.ROIWidth_pixels, app.tlCamera.ROIAndBin.ROIHeight_pixels] ...
                                    = coords{:};
                                confirm = questdlg('Are you satisfied with this region of interest?','ROI Select','Yes','No','Yes');
                                switch confirm
                                    case 'Yes'
                                        done = true;
                                end
                            end
                    end
                end
                % Save images and settings to current directory
                UpdateImageSettings(app)
                Settings = app.ImageSettings;
                Images = app.Images(app.tlCamera.ROIAndBin.ROIOriginX_pixels:app.tlCamera.ROIAndBin.ROIWidth_pixels,...
                    app.tlCamera.ROIAndBin.ROIOriginY_pixels:app.tlCamera.ROIAndBin.ROIHeight_pixels,:);
                Filename = sprintf('run_%s.mat', datestr(now,'mm-dd-yyyy HH-MM'));
                uisave({'Images', 'Settings'}, Filename);
            else
                % No images have been acquired
                f = uifigure;
                f.Position = [550, 350, 400, 200];
                uialert(f, 'No images to save.', 'Error')
            end
            app.ResetROIandBinButton.Enable = 'on';
            app.NewBackgroundImageButton.Enable = 'on';
            app.NewNormalizationImageButton.Enable = 'on';
            app.BeginImageAcquisitionButton.Enable = 'on'; 
            app.StopImageAcquisitionButton.Enable = 'on';
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            % Test background correction and normalization for errors
            % Indicate test results with lamp color
            errorTolerance = app.ErrorToleranceEditField.Value * 0.01;
            pass = true;
            % Open progress window
            f = uifigure;
            f.Position = [550, 350, 400, 200];
            f.Name = "Testing";
            d = uiprogressdlg(f,'Title','Testing Background Correction and Normalization', 'Indeterminate','on');
            % Testing 5 images, where each image is an average of 10 frames
            for i=1:5
                app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
                app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
                app.tlCamera.Arm;
                app.tlCamera.IssueSoftwareTrigger;
                frame = AverageFrame(app, 10);
                
                %{
                % Code for plotting pixels in error according to color-code
                % Coordinates for colored px
                [BlueX, BlueY] = find(app.CroppedN < app.CroppedB & frame > app.CroppedB);
                [RedX, RedY] = find(frame < app.CroppedB & app.CroppedN > app.CroppedB);
                [GreenX, GreenY] = find(app.CroppedN < app.CroppedB & frame < app.CroppedB);
                imagesc(frame','Parent',app.UIAxes);
                hold(app.UIAxes)
                % Add colored pixels to plot
                scatter(BlueX(:,1),BlueY(:,1),3,'b','filled','s','MarkerFaceAlpha',0.2,'Parent',app.UIAxes);
                scatter(RedX(:,1),RedY(:,1),3,'r','filled','s','MarkerFaceAlpha',0.2,'Parent',app.UIAxes);
                scatter(GreenX(:,1),GreenY(:,1),3,'g','filled','s','MarkerFaceAlpha',0.2,'Parent',app.UIAxes);
                % Update plot title
                app.UIAxes.Title.String = sprintf('Testing');
                drawnow();
                %}
                
                app.tlCamera.Disarm;
                               
                % Number of pixels in error
                errors = sum(app.CroppedN < app.CroppedB & frame > app.CroppedB, [1,2]);
                errors = errors + sum(frame < app.CroppedB & app.CroppedN > app.CroppedB, [1,2]);
                errors = errors + sum(app.CroppedN < app.CroppedB & frame < app.CroppedB, [1,2]);
                
                totalPx = (app.tlCamera.ROIAndBin.ROIWidth_pixels / app.tlCamera.ROIAndBin.BinX) .* (app.tlCamera.ROIAndBin.ROIHeight_pixels / app.tlCamera.ROIAndBin.BinY);
                
                % If any image fails, the test fails
                if errors > errorTolerance*totalPx
                    pass = false;
                    break;
                end
                
            end
            
            % If no tests fail, the lamp turns green
            % If any test fails, the lamp turns red
            switch pass
                case true
                    app.Lamp.Color = 'g';
                case false
                    app.Lamp.Color = 'r';
            end
            
            UpdateAndClearImageWindow(app);
            
            % Close progress window
            close(d)
            close(f)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThorlabsScientificCameraInterfaceUIFigure = uifigure('Visible', 'off');
            app.ThorlabsScientificCameraInterfaceUIFigure.Name = 'Thorlabs Scientific Camera Interface';
            app.ThorlabsScientificCameraInterfaceUIFigure.CloseRequestFcn = createCallbackFcn(app, @ThorlabsScientificCameraInterfaceUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.ThorlabsScientificCameraInterfaceUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0.5 640.5];
            app.UIAxes.YLim = [0.5 480.5];
            app.UIAxes.XTick = [0 100 200 300 400 500 600];
            app.UIAxes.XTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'; '600'};
            app.UIAxes.YTick = [0 100 200 300 400];
            app.UIAxes.YTickLabel = {'400'; '300'; '200'; '100'; '0'};
            app.NextButton = uibutton(app.ThorlabsScientificCameraInterfaceUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next >>';
            app.PreviousButton = uibutton(app.ThorlabsScientificCameraInterfaceUIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '<< Previous';
            app.ImageAcquisitionPanel = uipanel(app.ThorlabsScientificCameraInterfaceUIFigure);
            app.ImageAcquisitionPanel.Title = 'Image Acquisition';
            app.NewBackgroundImageButton = uibutton(app.ImageAcquisitionPanel, 'push');
            app.NewBackgroundImageButton.ButtonPushedFcn = createCallbackFcn(app, @NewBackgroundImageButtonPushed, true);
            app.NewBackgroundImageButton.Text = 'New Background Image';
            app.NewNormalizationImageButton = uibutton(app.ImageAcquisitionPanel, 'push');
            app.NewNormalizationImageButton.ButtonPushedFcn = createCallbackFcn(app, @NewNormalizationImageButtonPushed, true);
            app.NewNormalizationImageButton.Text = 'New Normalization Image';
            app.BeginImageAcquisitionButton = uibutton(app.ImageAcquisitionPanel, 'push');
            app.BeginImageAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @BeginImageAcquisitionButtonPushed, true);
            app.BeginImageAcquisitionButton.Text = 'Begin Image Acquisition';
            app.FramestoAverageforBackgroundEditFieldLabel = uilabel(app.ImageAcquisitionPanel);
            app.FramestoAverageforBackgroundEditFieldLabel.Text = 'Frames to Average for Background';
            app.FramestoAverageforBackgroundEditField = uieditfield(app.ImageAcquisitionPanel, 'numeric');
            app.FramestoAverageforBackgroundEditField.Value = 1;
            app.FramestoAverageforNormalizationEditFieldLabel = uilabel(app.ImageAcquisitionPanel);
            app.FramestoAverageforNormalizationEditFieldLabel.Text = 'Frames to Average for Normalization';
            app.FramestoAverageforNormalizationEditField = uieditfield(app.ImageAcquisitionPanel, 'numeric');
            app.FramestoAverageforNormalizationEditField.Value = 1;
            app.ExposureTimeusEditFieldLabel = uilabel(app.ImageAcquisitionPanel);
            app.ExposureTimeusEditFieldLabel.Text = 'Exposure Time (us)';
            app.ExposureTimeusEditField = uieditfield(app.ImageAcquisitionPanel, 'numeric');
            app.ExposureTimeusEditField.Limits = [0 Inf];
            app.ExposureTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeusEditFieldValueChanged, true);
            app.ExposureTimeusEditField.Value = 2000;
            app.FramestoAcquire0forunlimitedEditFieldLabel = uilabel(app.ImageAcquisitionPanel);
            app.FramestoAcquire0forunlimitedEditFieldLabel.Text = 'Frames to Acquire (0 for unlimited)';
            app.FramestoAcquire0forunlimitedEditField = uieditfield(app.ImageAcquisitionPanel, 'numeric');
            app.FramestoAcquire0forunlimitedEditField.Limits = [0 Inf];
            app.FramestoAcquire0forunlimitedEditField.Value = 1000;
            app.StopImageAcquisitionButton = uibutton(app.ImageAcquisitionPanel, 'push');
            app.StopImageAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StopImageAcquisitionButtonPushed, true);
            app.StopImageAcquisitionButton.Text = 'Stop Image Acquisition';
            app.TriggerModeButtonGroup = uibuttongroup(app.ImageAcquisitionPanel);
            app.TriggerModeButtonGroup.Title = 'Trigger Mode';
            app.ExternalButton = uiradiobutton(app.TriggerModeButtonGroup);
            app.ExternalButton.Text = 'External';
            app.InternalButton = uiradiobutton(app.TriggerModeButtonGroup);
            app.InternalButton.Text = 'Internal';
            app.InternalButton.Value = true;
            app.FramestoAverageEditFieldLabel = uilabel(app.ImageAcquisitionPanel);
            app.FramestoAverageEditFieldLabel.Text = 'Frames to Average';
            app.FramestoAverageEditField = uieditfield(app.ImageAcquisitionPanel, 'numeric');
            app.FramestoAverageEditField.Limits = [0 Inf];
            app.FramestoAverageEditField.Value = 100;
            app.FigureSettingsPanel = uipanel(app.ThorlabsScientificCameraInterfaceUIFigure);
            app.FigureSettingsPanel.Title = 'Figure Settings';
            app.RegionofInterestandBinningPanel = uipanel(app.FigureSettingsPanel);
            app.RegionofInterestandBinningPanel.Title = 'Region of Interest and Binning';
            app.OriginXEditFieldLabel = uilabel(app.RegionofInterestandBinningPanel);
            app.OriginXEditFieldLabel.Text = 'Origin-X';
            app.OriginXEditField = uieditfield(app.RegionofInterestandBinningPanel, 'numeric');
            app.OriginYEditFieldLabel = uilabel(app.RegionofInterestandBinningPanel);
            app.OriginYEditFieldLabel.Text = 'Origin-Y';
            app.OriginYEditField = uieditfield(app.RegionofInterestandBinningPanel, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.RegionofInterestandBinningPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.RegionofInterestandBinningPanel, 'numeric');
            app.WidthEditField.Value = 640;
            app.HeightEditFieldLabel = uilabel(app.RegionofInterestandBinningPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.RegionofInterestandBinningPanel, 'numeric');
            app.HeightEditField.Value = 480;
            app.ResetROIandBinButton = uibutton(app.RegionofInterestandBinningPanel, 'push');
            app.ResetROIandBinButton.ButtonPushedFcn = createCallbackFcn(app, @ResetROIandBinButtonPushed, true);
            app.ResetROIandBinButton.Text = 'Reset ROI and Bin';
            app.BinXLabel = uilabel(app.RegionofInterestandBinningPanel);
            app.BinXLabel.Text = 'Bin-X';
            app.BinXEditField = uieditfield(app.RegionofInterestandBinningPanel, 'numeric');
            app.BinXEditField.Limits = [1 24];
            app.BinXEditField.RoundFractionalValues = 'on';
            app.BinXEditField.Value = 1;
            app.BinYEditFieldLabel = uilabel(app.RegionofInterestandBinningPanel);
            app.BinYEditFieldLabel.Text = 'Bin-Y';
            app.BinYEditField = uieditfield(app.RegionofInterestandBinningPanel, 'numeric');
            app.BinYEditField.Limits = [1 24];
            app.BinYEditField.RoundFractionalValues = 'on';
            app.BinYEditField.Value = 1;
            app.SetROIandBinButton = uibutton(app.RegionofInterestandBinningPanel, 'push');
            app.SetROIandBinButton.ButtonPushedFcn = createCallbackFcn(app, @SetROIandBinButtonPushed, true);
            app.SetROIandBinButton.Text = 'Set ROI and Bin';
            app.BackgroundCorrectionandNormalizationPanel = uipanel(app.FigureSettingsPanel);
            app.BackgroundCorrectionandNormalizationPanel.Title = 'Background Correction and Normalization';
            app.Switch = uiswitch(app.BackgroundCorrectionandNormalizationPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.ScaleFactorEditFieldLabel = uilabel(app.BackgroundCorrectionandNormalizationPanel);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.BackgroundCorrectionandNormalizationPanel, 'numeric');
            app.ScaleFactorEditField.Limits = [0 Inf];
            app.ScaleFactorEditField.Value = 1;
            app.TestButton = uibutton(app.BackgroundCorrectionandNormalizationPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test   ';
            app.Lamp = uilamp(app.BackgroundCorrectionandNormalizationPanel);
            app.ErrorToleranceLabel = uilabel(app.BackgroundCorrectionandNormalizationPanel);
            app.ErrorToleranceLabel.Text = 'Error Tolerance (%)';
            app.ErrorToleranceEditField = uieditfield(app.BackgroundCorrectionandNormalizationPanel, 'numeric');
            app.ErrorToleranceEditField.Limits = [0 100];
            app.ErrorToleranceEditField.Value = 100;
            app.ClearFigureWindowButton = uibutton(app.FigureSettingsPanel, 'push');
            app.ClearFigureWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureWindowButtonPushed, true);
            app.ClearFigureWindowButton.Text = 'Clear Figure Window';
            app.SaveImagesButton = uibutton(app.FigureSettingsPanel, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImagesButtonPushed, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.FrameRate0fpsLabel = uilabel(app.ThorlabsScientificCameraInterfaceUIFigure);
            app.FrameRate0fpsLabel.Visible = 'off';
            app.FrameRate0fpsLabel.Text = 'Frame Rate: 0 fps';
            % Show the figure after all components are created
            app.ThorlabsScientificCameraInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThorlabsScientificCameraInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThorlabsScientificCameraInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThorlabsScientificCameraInterfaceUIFigure)
        end
    end
end